classdef MainUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SessionTitlePanel              matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        SessionTab                     matlab.ui.container.Tab
        LoadButton                     matlab.ui.control.Button
        NewButton                      matlab.ui.control.Button
        TrajectoryTab                  matlab.ui.container.Tab
        AddPointsPanel                 matlab.ui.container.Panel
        B8SpinnerLabel                 matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        TypeDropDown                   matlab.ui.control.DropDown
        TypeDropDownLabel              matlab.ui.control.Label
        B8Spinner                      matlab.ui.control.Spinner
        B7Spinner                      matlab.ui.control.Spinner
        B7SpinnerLabel                 matlab.ui.control.Label
        B5Spinner                      matlab.ui.control.Spinner
        B5SpinnerLabel                 matlab.ui.control.Label
        B6Spinner                      matlab.ui.control.Spinner
        B6SpinnerLabel                 matlab.ui.control.Label
        B4Spinner                      matlab.ui.control.Spinner
        B4SpinnerLabel                 matlab.ui.control.Label
        B2Spinner                      matlab.ui.control.Spinner
        B2SpinnerLabel                 matlab.ui.control.Label
        B3Spinner                      matlab.ui.control.Spinner
        B3SpinnerLabel                 matlab.ui.control.Label
        B1Spinner                      matlab.ui.control.Spinner
        B1SpinnerLabel                 matlab.ui.control.Label
        TrajectoryViewPanel            matlab.ui.container.Panel
        SelectTrajectoryDropDown       matlab.ui.control.DropDown
        SelectTrajectoryDropDownLabel  matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ControllerTab                  matlab.ui.container.Tab
        NewControllerPanel             matlab.ui.container.Panel
        SaveButton_2                   matlab.ui.control.Button
        Spinner8                       matlab.ui.control.Spinner
        Spinner8Label                  matlab.ui.control.Label
        Spinner7                       matlab.ui.control.Spinner
        Spinner7Label                  matlab.ui.control.Label
        Spinner6                       matlab.ui.control.Spinner
        Spinner6Label                  matlab.ui.control.Label
        Spinner5                       matlab.ui.control.Spinner
        Spinner5Label                  matlab.ui.control.Label
        Spinner4                       matlab.ui.control.Spinner
        Spinner4Label                  matlab.ui.control.Label
        Spinner3                       matlab.ui.control.Spinner
        Spinner3Label                  matlab.ui.control.Label
        Spinner2                       matlab.ui.control.Spinner
        Spinner2Label                  matlab.ui.control.Label
        Spinner                        matlab.ui.control.Spinner
        SpinnerLabel                   matlab.ui.control.Label
        ControlTypeDropDown            matlab.ui.control.DropDown
        ControlTypeDropDownLabel       matlab.ui.control.Label
        ControllersPanel               matlab.ui.container.Panel
        ListBox                        matlab.ui.control.ListBox
        ProcessTab                     matlab.ui.container.Tab
        TaskListPanel                  matlab.ui.container.Panel
        GotoxEditField                 matlab.ui.control.NumericEditField
        GotoxEditFieldLabel            matlab.ui.control.Label
        AddButton_3                    matlab.ui.control.Button
        TaskListBox_Traj               matlab.ui.control.ListBox
        TaskListBox_Ctl                matlab.ui.control.ListBox
        AddButton_2                    matlab.ui.control.Button
        SetupTaskList                  matlab.ui.control.ListBox
        ProcessPanel                   matlab.ui.container.Panel
        SaveButton_3                   matlab.ui.control.Button
        DeleteButton                   matlab.ui.control.Button
        ProcessDropDown                matlab.ui.control.DropDown
        ProcessDropDownLabel           matlab.ui.control.Label
        ProcessList                    matlab.ui.control.ListBox
        RunTab                         matlab.ui.container.Tab
        ShutdownButton                 matlab.ui.control.Button
        DisturbanceDropDown            matlab.ui.control.DropDown
        DisturbanceDropDownLabel       matlab.ui.control.Label
        GameEditField                  matlab.ui.control.EditField
        GameEditFieldLabel             matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        Lamp_2                         matlab.ui.control.Lamp
        SetButton                      matlab.ui.control.Button
        IPAddressEditField             matlab.ui.control.EditField
        IPAddressEditFieldLabel        matlab.ui.control.Label
        TareButton                     matlab.ui.control.Button
        RunControllerDropdown          matlab.ui.control.DropDown
        ControllerLabel                matlab.ui.control.Label
        HomeButton                     matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        RunTrajectoryDropdown          matlab.ui.control.DropDown
        TrajectoryLabel                matlab.ui.control.Label
        UploadButton                   matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        ConnectButton                  matlab.ui.control.Button
        Axis_F                         matlab.ui.control.UIAxes
        Axis_x                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SessionName = '';
        SessionPath = '';
        CtlNames = {};
        CltParamNames = {};
        CtlParams = {};
        TrajNames = {};
        TrajData = {};
        ProcessNames = {};
        ProcessData = {};
        CurrentCtlParams = [];
        CurrentTrajData = [[0:0.001:1];zeros(1,1001)];
        CurrentProcessData = [];
        ipaddr = '';
        tcpConn;
        XDataAxis = [];
        X0DataAxis = [];
        FDataAxis = [];
    end

    properties (Access = public)
        
        TrajectoryParams = {'t0', 'tf', 'x0','A', 'A0', 'w'};
        StepParams = [1,2,3];
        SineParams = [1,2,4,5,6];
        ControlParams = {"kp","kp","Md","Bd","Kd","alpha","delta"};
        PD_Params = [1,2];
        Imp_Params = [3,4,5];
        Adm_Params = [1,2,3,4,5];
        UIAC_Params = [1,2,3,4,5,6,7];
        UIC_Params = [1,2,3,4,5,6,7];

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NewButton
        function NewSessionButton(app, event)
            newSessionButton(app,event);
            
        end

        % Value changed function: TypeDropDown
        function ChangeTrajectoryType(app, event)
            value = app.TypeDropDown.Value;
            changeTrajectoryType(app,value);
        end

        % Button pushed function: AddButton
        function AddTrajectoriesPoints(app, event)
            addTrajectoriesPoints(app);
        end

        % Button pushed function: SaveButton
        function SaveTrajectoryButton(app, event)
            saveTrajectoryButton(app);            
        end

        % Value changed function: SelectTrajectoryDropDown
        function SelectTrajectoryDrop(app, event)
            selectTrajectoryDrop(app);
        end

        % Button pushed function: LoadButton
        function LoadSession(app, event)
            loadSession(app);
        end

        % Button pushed function: ResetButton
        function ResetTrajectory(app, event)
            resetTrajectory(app);
        end

        % Value changed function: ControlTypeDropDown
        function ControlTypeDrop(app, event)
            value = app.ControlTypeDropDown.Value;
            controlTypeDrop(app,value);
        end

        % Button pushed function: SaveButton_2
        function SaveController(app, event)
            saveController(app);

        end

        % Value changed function: ListBox
        function ChangeController(app, event)
            value = app.ListBox.ValueIndex;
            changeController(app,value);
            ControlTypeDrop(app, event);
        end

        % Button pushed function: AddButton_2
        function AddTask(app, event)
            addTask(app);
        end

        % Button pushed function: AddButton_3
        function AddCtlTask(app, event)
            addCtlTask(app);
        end

        % Button pushed function: SaveButton_3
        function SaveProcess(app, event)
            saveProcess(app);
        end

        % Button pushed function: DeleteButton
        function DeleteProcessTask(app, event)
            deleteProcessTask(app);
        end

        % Value changed function: ProcessDropDown
        function ChangeProcess(app, event)
            value = app.ProcessDropDown.ValueIndex;
            changeProcess(app, value);
        end

        % Button pushed function: UploadButton
        function UploadFiles(app, event)
            uploadFiles(app);
        end

        % Button pushed function: ConnectButton
        function ConnectTCP(app, event)
            connectTCP(app);
        end

        % Button pushed function: RunButton
        function RunRobot(app, event)
            runRobot(app);
        end

        % Callback function
        function NextButton(app, event)
            nextButton(app);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            homeButton(app);
        end

        % Button pushed function: TareButton
        function TareButton2(app, event)
            tareButton(app);
        end

        % Button pushed function: SetButton
        function SetButton2(app, event)
           setButton(app);
        end

        % Button pushed function: StopButton
        function StopButtonPressed(app, event)
            stopButton(app);
        end

        % Button pushed function: ShutdownButton
        function shutdownButtonPressed(app, event)
            shutdownButton(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SessionTitlePanel = uipanel(app.UIFigure);
            app.SessionTitlePanel.Title = 'Session Title';
            app.TabGroup = uitabgroup(app.SessionTitlePanel);
            app.SessionTab = uitab(app.TabGroup);
            app.SessionTab.Title = 'Session';
            app.NewButton = uibutton(app.SessionTab, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewSessionButton, true);
            app.NewButton.Text = 'New';
            app.LoadButton = uibutton(app.SessionTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSession, true);
            app.LoadButton.Text = 'Load';
            app.TrajectoryTab = uitab(app.TabGroup);
            app.TrajectoryTab.Title = 'Trajectory';
            app.TrajectoryViewPanel = uipanel(app.TrajectoryTab);
            app.TrajectoryViewPanel.Title = 'Trajectory View';
            app.UIAxes = uiaxes(app.TrajectoryViewPanel);
            ylabel(app.UIAxes, 'X')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes2 = uiaxes(app.TrajectoryViewPanel);
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'V')
            zlabel(app.UIAxes2, 'Z')
            app.SelectTrajectoryDropDownLabel = uilabel(app.TrajectoryViewPanel);
            app.SelectTrajectoryDropDownLabel.Text = 'Select Trajectory';
            app.SelectTrajectoryDropDown = uidropdown(app.TrajectoryViewPanel);
            app.SelectTrajectoryDropDown.Items = {''};
            app.SelectTrajectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTrajectoryDrop, true);
            app.SelectTrajectoryDropDown.Value = '';
            app.AddPointsPanel = uipanel(app.TrajectoryTab);
            app.AddPointsPanel.Title = 'Add Points';
            app.B1SpinnerLabel = uilabel(app.AddPointsPanel);
            app.B1SpinnerLabel.Text = 'B1';
            app.B1Spinner = uispinner(app.AddPointsPanel);
            app.B1Spinner.Tag = 'kp';
            app.B3SpinnerLabel = uilabel(app.AddPointsPanel);
            app.B3SpinnerLabel.Text = 'B3';
            app.B3Spinner = uispinner(app.AddPointsPanel);
            app.B3Spinner.Tag = 'kv';
            app.B2SpinnerLabel = uilabel(app.AddPointsPanel);
            app.B2SpinnerLabel.Text = 'B2';
            app.B2Spinner = uispinner(app.AddPointsPanel);
            app.B2Spinner.Tag = 'kv';
            app.B4SpinnerLabel = uilabel(app.AddPointsPanel);
            app.B4SpinnerLabel.Text = 'B4';
            app.B4Spinner = uispinner(app.AddPointsPanel);
            app.B4Spinner.Tag = 'kv';
            app.B6SpinnerLabel = uilabel(app.AddPointsPanel);
            app.B6SpinnerLabel.Text = 'B6';
            app.B6Spinner = uispinner(app.AddPointsPanel);
            app.B6Spinner.Tag = 'kv';
            app.B5SpinnerLabel = uilabel(app.AddPointsPanel);
            app.B5SpinnerLabel.Text = 'B5';
            app.B5Spinner = uispinner(app.AddPointsPanel);
            app.B5Spinner.Tag = 'kv';
            app.B7SpinnerLabel = uilabel(app.AddPointsPanel);
            app.B7SpinnerLabel.Text = 'B7';
            app.B7Spinner = uispinner(app.AddPointsPanel);
            app.B7Spinner.Tag = 'kv';
            app.B8Spinner = uispinner(app.AddPointsPanel);
            app.TypeDropDownLabel = uilabel(app.AddPointsPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.AddPointsPanel);
            app.TypeDropDown.Items = {'Step', 'Sine'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeTrajectoryType, true);
            app.TypeDropDown.Value = 'Step';
            app.AddButton = uibutton(app.AddPointsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrajectoriesPoints, true);
            app.AddButton.Text = 'Add';
            app.SaveButton = uibutton(app.AddPointsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTrajectoryButton, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.AddPointsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTrajectory, true);
            app.ResetButton.Text = 'Reset';
            app.B8SpinnerLabel = uilabel(app.AddPointsPanel);
            app.B8SpinnerLabel.Text = 'B8';
            app.ControllerTab = uitab(app.TabGroup);
            app.ControllerTab.Title = 'Controller';
            app.ControllersPanel = uipanel(app.ControllerTab);
            app.ControllersPanel.Title = 'Controllers';
            app.ListBox = uilistbox(app.ControllersPanel);
            app.ListBox.Items = {''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ChangeController, true);
            app.ListBox.Value = '';
            app.NewControllerPanel = uipanel(app.ControllerTab);
            app.NewControllerPanel.Title = 'New Controller';
            app.ControlTypeDropDownLabel = uilabel(app.NewControllerPanel);
            app.ControlTypeDropDownLabel.Text = 'Control Type';
            app.ControlTypeDropDown = uidropdown(app.NewControllerPanel);
            app.ControlTypeDropDown.Items = {'PD', 'Impedance', 'Admittance', 'UIC', 'UIAC'};
            app.ControlTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlTypeDrop, true);
            app.ControlTypeDropDown.Value = 'PD';
            app.SpinnerLabel = uilabel(app.NewControllerPanel);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.NewControllerPanel);
            app.Spinner2Label = uilabel(app.NewControllerPanel);
            app.Spinner2Label.Text = 'Spinner2';
            app.Spinner2 = uispinner(app.NewControllerPanel);
            app.Spinner3Label = uilabel(app.NewControllerPanel);
            app.Spinner3Label.Text = 'Spinner3';
            app.Spinner3 = uispinner(app.NewControllerPanel);
            app.Spinner4Label = uilabel(app.NewControllerPanel);
            app.Spinner4Label.Text = 'Spinner4';
            app.Spinner4 = uispinner(app.NewControllerPanel);
            app.Spinner5Label = uilabel(app.NewControllerPanel);
            app.Spinner5Label.Text = 'Spinner5';
            app.Spinner5 = uispinner(app.NewControllerPanel);
            app.Spinner6Label = uilabel(app.NewControllerPanel);
            app.Spinner6Label.Text = 'Spinner6';
            app.Spinner6 = uispinner(app.NewControllerPanel);
            app.Spinner7Label = uilabel(app.NewControllerPanel);
            app.Spinner7Label.Text = 'Spinner7';
            app.Spinner7 = uispinner(app.NewControllerPanel);
            app.Spinner8Label = uilabel(app.NewControllerPanel);
            app.Spinner8Label.Text = 'Spinner8';
            app.Spinner8 = uispinner(app.NewControllerPanel);
            app.SaveButton_2 = uibutton(app.NewControllerPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveController, true);
            app.SaveButton_2.Text = 'Save';
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.ProcessPanel = uipanel(app.ProcessTab);
            app.ProcessPanel.Title = 'Process';
            app.ProcessList = uilistbox(app.ProcessPanel);
            app.ProcessList.Items = {};
            app.ProcessList.Value = {};
            app.ProcessDropDownLabel = uilabel(app.ProcessPanel);
            app.ProcessDropDownLabel.Text = 'Process';
            app.ProcessDropDown = uidropdown(app.ProcessPanel);
            app.ProcessDropDown.Items = {};
            app.ProcessDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeProcess, true);
            app.ProcessDropDown.Value = {};
            app.DeleteButton = uibutton(app.ProcessPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProcessTask, true);
            app.DeleteButton.Text = 'Delete';
            app.SaveButton_3 = uibutton(app.ProcessPanel, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveProcess, true);
            app.SaveButton_3.Text = 'Save';
            app.TaskListPanel = uipanel(app.ProcessTab);
            app.TaskListPanel.Title = 'Task List';
            app.SetupTaskList = uilistbox(app.TaskListPanel);
            app.SetupTaskList.Items = {'Home to back', 'Home to front and back', 'Tare', 'Goto'};
            app.SetupTaskList.Value = 'Home to back';
            app.AddButton_2 = uibutton(app.TaskListPanel, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddTask, true);
            app.AddButton_2.Text = 'Add';
            app.TaskListBox_Ctl = uilistbox(app.TaskListPanel);
            app.TaskListBox_Ctl.Items = {};
            app.TaskListBox_Ctl.Value = {};
            app.TaskListBox_Traj = uilistbox(app.TaskListPanel);
            app.TaskListBox_Traj.Items = {};
            app.TaskListBox_Traj.Value = {};
            app.AddButton_3 = uibutton(app.TaskListPanel, 'push');
            app.AddButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddCtlTask, true);
            app.AddButton_3.Text = 'Add';
            app.GotoxEditFieldLabel = uilabel(app.TaskListPanel);
            app.GotoxEditFieldLabel.Text = 'Goto x';
            app.GotoxEditField = uieditfield(app.TaskListPanel, 'numeric');
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.Axis_x = uiaxes(app.RunTab);
            ylabel(app.Axis_x, 'X')
            zlabel(app.Axis_x, 'Z')
            app.Axis_F = uiaxes(app.RunTab);
            ylabel(app.Axis_F, 'F')
            zlabel(app.Axis_F, 'Z')
            app.ConnectButton = uibutton(app.RunTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectTCP, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp = uilamp(app.RunTab);
            app.Lamp.Enable = 'off';
            app.UploadButton = uibutton(app.RunTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFiles, true);
            app.UploadButton.Text = 'Upload';
            app.TrajectoryLabel = uilabel(app.RunTab);
            app.TrajectoryLabel.Text = 'Trajectory';
            app.RunTrajectoryDropdown = uidropdown(app.RunTab);
            app.RunTrajectoryDropdown.Items = {};
            app.RunTrajectoryDropdown.Value = {};
            app.RunButton = uibutton(app.RunTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunRobot, true);
            app.RunButton.Text = 'Run';
            app.HomeButton = uibutton(app.RunTab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.ControllerLabel = uilabel(app.RunTab);
            app.ControllerLabel.Text = 'Controller';
            app.RunControllerDropdown = uidropdown(app.RunTab);
            app.RunControllerDropdown.Items = {};
            app.RunControllerDropdown.Value = {};
            app.TareButton = uibutton(app.RunTab, 'push');
            app.TareButton.ButtonPushedFcn = createCallbackFcn(app, @TareButton2, true);
            app.TareButton.Text = 'Tare';
            app.IPAddressEditFieldLabel = uilabel(app.RunTab);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.RunTab, 'text');
            app.IPAddressEditField.Value = '192.168.0.125';
            app.SetButton = uibutton(app.RunTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButton2, true);
            app.SetButton.Text = 'Set';
            app.Lamp_2 = uilamp(app.RunTab);
            app.Lamp_2.Enable = 'off';
            app.StopButton = uibutton(app.RunTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPressed, true);
            app.StopButton.Text = 'Stop';
            app.GameEditFieldLabel = uilabel(app.RunTab);
            app.GameEditFieldLabel.Text = 'Game';
            app.GameEditField = uieditfield(app.RunTab, 'text');
            app.GameEditField.Value = 'RobotGuide';
            app.DisturbanceDropDownLabel = uilabel(app.RunTab);
            app.DisturbanceDropDownLabel.Text = 'Disturbance';
            app.DisturbanceDropDown = uidropdown(app.RunTab);
            app.DisturbanceDropDown.Items = {};
            app.DisturbanceDropDown.Value = {};
            app.ShutdownButton = uibutton(app.RunTab, 'push');
            app.ShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @shutdownButtonPressed, true);
            app.ShutdownButton.Text = 'Shutdown';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end